classdef Test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SelectimageandgeneratedatfilePanel  matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        BrowseButton                   matlab.ui.control.Button
        ModulationTypeDropDown_2Label  matlab.ui.control.Label
        ModulationTypeDropDown_2       matlab.ui.control.DropDown
        ImageLabel                     matlab.ui.control.Label
        ImageEditField                 matlab.ui.control.EditField
        GenerateTxFileButton           matlab.ui.control.Button
        DownloadmatoforiginaldataButton  matlab.ui.control.Button
        TransmitandReceiveDataPanel    matlab.ui.container.Panel
        EnsurethatyourUSRPsareconnectedtoyourlaptopLabel  matlab.ui.control.Label
        UploadNewDataButton            matlab.ui.control.Button
        SelectTxDataDropDownLabel      matlab.ui.control.Label
        SelectTxDataDropDown           matlab.ui.control.DropDown
        TransmitandReceiveDataPanel_2  matlab.ui.container.Panel
        TransmitterButtonGroup         matlab.ui.container.ButtonGroup
        C62D1Button                    matlab.ui.control.RadioButton
        CF9DBButton                    matlab.ui.control.RadioButton
        ReceiverButtonGroup            matlab.ui.container.ButtonGroup
        C62D1Button_2                  matlab.ui.control.RadioButton
        CF9DBButton_2                  matlab.ui.control.RadioButton
        TransmitDataButton             matlab.ui.control.Button
        ReceiveSwitchLabel             matlab.ui.control.Label
        ReceiveSwitch                  matlab.ui.control.Switch
        RxDatanameEditFieldLabel       matlab.ui.control.Label
        RxDatanameEditField            matlab.ui.control.EditField
        ImageTypeLabel                 matlab.ui.control.Label
        ImageTypeDropDown              matlab.ui.control.DropDown
        DemodulateDataandCalculateErrorRatePanel  matlab.ui.container.Panel
        Image_2                        matlab.ui.control.Image
        BrowseButton_2                 matlab.ui.control.Button
        ModulationTypeDropDown_3Label  matlab.ui.control.Label
        ModulationTypeDropDown_3       matlab.ui.control.DropDown
        RxFileEditFieldLabel           matlab.ui.control.Label
        RxFileEditField                matlab.ui.control.EditField
        DemodulateImageButton          matlab.ui.control.Button
        ImageTypeDropDown_2Label       matlab.ui.control.Label
        ImageTypeDropDown_2            matlab.ui.control.DropDown
        RetrievedImageLabel            matlab.ui.control.Label
        SaveImageButton                matlab.ui.control.Button
        matFileEditFieldLabel          matlab.ui.control.Label
        matFileEditField               matlab.ui.control.EditField
        BrowseButton_3                 matlab.ui.control.Button
        BitErrorRateEditFieldLabel     matlab.ui.control.Label
        BitErrorRateEditField          matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        selectedPath='C:\Users\kguerra\Desktop\ModulationandImages' % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            plot(app.UIAxes,value*peaks)
            app.UIAxes.YLim = [-1000 1000];
        end

        % Button pushed function: GenerateTxFileButton, 
        % UploadNewDataButton
        function GeneratedatFileButtonPushed(app, event)
            app.selectedPath = uigetdir();
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [image_selected] = uigetfile('*.jpg','*.png','Select Image');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectimageandgeneratedatfilePanel = uipanel(app.UIFigure);
            app.SelectimageandgeneratedatfilePanel.Title = 'Select image and generate .dat file';
            app.Image = uiimage(app.SelectimageandgeneratedatfilePanel);
            app.BrowseButton = uibutton(app.SelectimageandgeneratedatfilePanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ModulationTypeDropDown_2Label = uilabel(app.SelectimageandgeneratedatfilePanel);
            app.ModulationTypeDropDown_2Label.Text = 'Modulation Type:';
            app.ModulationTypeDropDown_2 = uidropdown(app.SelectimageandgeneratedatfilePanel);
            app.ModulationTypeDropDown_2.Items = {'BPSK', 'OFDM'};
            app.ModulationTypeDropDown_2.Value = 'BPSK';
            app.ImageLabel = uilabel(app.SelectimageandgeneratedatfilePanel);
            app.ImageLabel.Text = 'Image:';
            app.ImageEditField = uieditfield(app.SelectimageandgeneratedatfilePanel, 'text');
            app.GenerateTxFileButton = uibutton(app.SelectimageandgeneratedatfilePanel, 'push');
            app.GenerateTxFileButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratedatFileButtonPushed, true);
            app.GenerateTxFileButton.Text = 'Generate Tx File';
            app.DownloadmatoforiginaldataButton = uibutton(app.SelectimageandgeneratedatfilePanel, 'push');
            app.DownloadmatoforiginaldataButton.Text = 'Download .mat of original data';
            app.TransmitandReceiveDataPanel = uipanel(app.UIFigure);
            app.TransmitandReceiveDataPanel.Title = 'Transmit and Receive Data';
            app.EnsurethatyourUSRPsareconnectedtoyourlaptopLabel = uilabel(app.TransmitandReceiveDataPanel);
            app.EnsurethatyourUSRPsareconnectedtoyourlaptopLabel.Text = {'*Ensure that your USRPs are connected to your laptop'; ''};
            app.UploadNewDataButton = uibutton(app.TransmitandReceiveDataPanel, 'push');
            app.UploadNewDataButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratedatFileButtonPushed, true);
            app.UploadNewDataButton.Text = 'Upload New Data';
            app.SelectTxDataDropDownLabel = uilabel(app.TransmitandReceiveDataPanel);
            app.SelectTxDataDropDownLabel.Text = 'Select Tx Data:';
            app.SelectTxDataDropDown = uidropdown(app.TransmitandReceiveDataPanel);
            app.SelectTxDataDropDown.Items = {'BPSK_Dat.dat', 'OFDM_Dat.dat', 'PAM_Dat.dat', 'QAM_Dat.dat'};
            app.SelectTxDataDropDown.Value = 'BPSK_Dat.dat';
            app.TransmitandReceiveDataPanel_2 = uipanel(app.TransmitandReceiveDataPanel);
            app.TransmitandReceiveDataPanel_2.Title = 'Transmit and Receive Data';
            app.TransmitterButtonGroup = uibuttongroup(app.TransmitandReceiveDataPanel_2);
            app.TransmitterButtonGroup.Title = 'Transmitter';
            app.C62D1Button = uiradiobutton(app.TransmitterButtonGroup);
            app.C62D1Button.Text = '30C62D1';
            app.C62D1Button.Value = true;
            app.CF9DBButton = uiradiobutton(app.TransmitterButtonGroup);
            app.CF9DBButton.Text = '30CF9DB';
            app.ReceiverButtonGroup = uibuttongroup(app.TransmitandReceiveDataPanel_2);
            app.ReceiverButtonGroup.Title = 'Receiver';
            app.C62D1Button_2 = uiradiobutton(app.ReceiverButtonGroup);
            app.C62D1Button_2.Text = '30C62D1';
            app.C62D1Button_2.Value = true;
            app.CF9DBButton_2 = uiradiobutton(app.ReceiverButtonGroup);
            app.CF9DBButton_2.Text = '30CF9DB';
            app.TransmitDataButton = uibutton(app.TransmitandReceiveDataPanel_2, 'push');
            app.TransmitDataButton.Text = 'Transmit Data';
            app.ReceiveSwitchLabel = uilabel(app.TransmitandReceiveDataPanel_2);
            app.ReceiveSwitchLabel.Text = 'Receive';
            app.ReceiveSwitch = uiswitch(app.TransmitandReceiveDataPanel_2, 'slider');
            app.RxDatanameEditFieldLabel = uilabel(app.TransmitandReceiveDataPanel);
            app.RxDatanameEditFieldLabel.Text = 'Rx Data name:';
            app.RxDatanameEditField = uieditfield(app.TransmitandReceiveDataPanel, 'text');
            app.ImageTypeLabel = uilabel(app.UIFigure);
            app.ImageTypeLabel.Text = 'Image Type: ';
            app.ImageTypeDropDown = uidropdown(app.UIFigure);
            app.ImageTypeDropDown.Items = {'Grayscale', 'RGB'};
            app.ImageTypeDropDown.Value = 'Grayscale';
            app.DemodulateDataandCalculateErrorRatePanel = uipanel(app.UIFigure);
            app.DemodulateDataandCalculateErrorRatePanel.Title = 'Demodulate Data and Calculate Error Rate';
            app.Image_2 = uiimage(app.DemodulateDataandCalculateErrorRatePanel);
            app.BrowseButton_2 = uibutton(app.DemodulateDataandCalculateErrorRatePanel, 'push');
            app.BrowseButton_2.Text = 'Browse';
            app.ModulationTypeDropDown_3Label = uilabel(app.DemodulateDataandCalculateErrorRatePanel);
            app.ModulationTypeDropDown_3Label.Text = 'Modulation Type:';
            app.ModulationTypeDropDown_3 = uidropdown(app.DemodulateDataandCalculateErrorRatePanel);
            app.ModulationTypeDropDown_3.Items = {'BPSK', 'OFDM'};
            app.ModulationTypeDropDown_3.Value = 'BPSK';
            app.RxFileEditFieldLabel = uilabel(app.DemodulateDataandCalculateErrorRatePanel);
            app.RxFileEditFieldLabel.Text = 'Rx File:';
            app.RxFileEditField = uieditfield(app.DemodulateDataandCalculateErrorRatePanel, 'text');
            app.DemodulateImageButton = uibutton(app.DemodulateDataandCalculateErrorRatePanel, 'push');
            app.DemodulateImageButton.Text = 'Demodulate Image';
            app.ImageTypeDropDown_2Label = uilabel(app.DemodulateDataandCalculateErrorRatePanel);
            app.ImageTypeDropDown_2Label.Text = 'Image Type: ';
            app.ImageTypeDropDown_2 = uidropdown(app.DemodulateDataandCalculateErrorRatePanel);
            app.ImageTypeDropDown_2.Items = {'Grayscale', 'RGB'};
            app.ImageTypeDropDown_2.Value = 'Grayscale';
            app.RetrievedImageLabel = uilabel(app.DemodulateDataandCalculateErrorRatePanel);
            app.RetrievedImageLabel.Text = 'Retrieved Image';
            app.SaveImageButton = uibutton(app.DemodulateDataandCalculateErrorRatePanel, 'push');
            app.SaveImageButton.Text = 'Save Image';
            app.matFileEditFieldLabel = uilabel(app.DemodulateDataandCalculateErrorRatePanel);
            app.matFileEditFieldLabel.Text = '.mat File';
            app.matFileEditField = uieditfield(app.DemodulateDataandCalculateErrorRatePanel, 'text');
            app.BrowseButton_3 = uibutton(app.DemodulateDataandCalculateErrorRatePanel, 'push');
            app.BrowseButton_3.Text = 'Browse';
            app.BitErrorRateEditFieldLabel = uilabel(app.DemodulateDataandCalculateErrorRatePanel);
            app.BitErrorRateEditFieldLabel.Text = 'Bit Error Rate';
            app.BitErrorRateEditField = uieditfield(app.DemodulateDataandCalculateErrorRatePanel, 'numeric');
            app.UIAxes = uiaxes(app.DemodulateDataandCalculateErrorRatePanel);
            title(app.UIAxes, 'Rx vs Tx')
            xlabel(app.UIAxes, 'Bit')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end